classdef Hodograph_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        TitlePageTab                  matlab.ui.container.Tab
        Image                         matlab.ui.control.Image
        HodographMethodforGravityWaveDetectionLabel  matlab.ui.control.Label
        DevelopedbyThomasColliganZachYapKatelynPowellLabel  matlab.ui.control.Label
        wind_shearTab                 matlab.ui.container.Tab
        RunButton                     matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        UIAxes6                       matlab.ui.control.UIAxes
        UITable2                      matlab.ui.control.Table
        filenameLabel                 matlab.ui.control.Label
        SelectRunSelectdatafileforwindsheardetectionLabel  matlab.ui.control.Label
        Plot_3DTab                    matlab.ui.container.Tab
        Plot_3DButton                 matlab.ui.control.Button
        plot_3D                       matlab.ui.control.UIAxes
        min_3DSliderLabel             matlab.ui.control.Label
        min_3DSlider                  matlab.ui.control.Slider
        max_3DSliderLabel             matlab.ui.control.Label
        max_3DSlider                  matlab.ui.control.Slider
        FIlenameEditFieldLabel        matlab.ui.control.Label
        FIlenameEditField             matlab.ui.control.EditField
        Max_altEditFieldLabel         matlab.ui.control.Label
        Max_altEditField              matlab.ui.control.NumericEditField
        Min_altEditFieldLabel         matlab.ui.control.Label
        Min_altEditField              matlab.ui.control.NumericEditField
        Label_2                       matlab.ui.control.Label
        Hodograph_RunTab              matlab.ui.container.Tab
        StartButton                   matlab.ui.control.Button
        Next_FileButton               matlab.ui.control.Button
        Save_DataButton               matlab.ui.control.Button
        Data_DirectoryEditFieldLabel  matlab.ui.control.Label
        Data_DirectoryEditField       matlab.ui.control.EditField
        UIAxes5                       matlab.ui.control.UIAxes
        Current_FileEditField_2Label  matlab.ui.control.Label
        Current_FileEditField         matlab.ui.control.EditField
        WarningsEditFieldLabel        matlab.ui.control.Label
        WarningsEditField             matlab.ui.control.EditField
        Save_Data_DIrectoryEditFieldLabel  matlab.ui.control.Label
        Save_Data_DIrectoryEditField  matlab.ui.control.EditField
        Main_DirectoryEditFieldLabel  matlab.ui.control.Label
        Main_DirectoryEditField       matlab.ui.control.EditField
        Min_ALTSpinnerLabel           matlab.ui.control.Label
        Min_ALTSpinner                matlab.ui.control.Spinner
        Max_ALTSpinnerLabel           matlab.ui.control.Label
        Max_ALTSpinner                matlab.ui.control.Spinner
        Label_3                       matlab.ui.control.Label
        Image3                        matlab.ui.control.Image
        Exampleellipsewithaltituderangeof18400to19300mLabel  matlab.ui.control.Label
        RotationDropDownLabel         matlab.ui.control.Label
        RotationDropDown              matlab.ui.control.DropDown
        DirectoryButton               matlab.ui.control.Button
        Plot_HodographTab             matlab.ui.container.Tab
        GridLayout                    matlab.ui.container.GridLayout
        PlotButton                    matlab.ui.control.Button
        Label_4                       matlab.ui.control.Label
        Wavelet_analysisTab           matlab.ui.container.Tab
        dataDirectoryButton           matlab.ui.control.Button
        dataDirectoryEditFieldLabel   matlab.ui.control.Label
        dataDirectoryEditField        matlab.ui.control.EditField
        saveDirectoryButton           matlab.ui.control.Button
        saveDirectoryEditFieldLabel   matlab.ui.control.Label
        saveDirectoryEditField        matlab.ui.control.EditField
        RunButton_2                   matlab.ui.control.Button
        Power_SurfaceSwitchLabel      matlab.ui.control.Label
        Power_SurfaceSwitch           matlab.ui.control.Switch
        Save_DataSwitchLabel          matlab.ui.control.Label
        Save_DataSwitch               matlab.ui.control.Switch
        latitudeEditFieldLabel        matlab.ui.control.Label
        latitudeEditField             matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        temp;
        data;
        Alt;
        u;
        v;
        bvFreqSquared;
        upper;
        lower;
        i = 1;
        wl = 12000;
        wu = 33000;
        filesp
    end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            warning('off','MATLAB:table:ModifiedAndSavedVarnames')
        end

        % Button pushed function: Plot_3DButton
        function Plot_3DButtonPushed(app, event)
            [file, path] = uigetfile('*.txt');
            fullfilename = fullfile(path,file);
            app.FIlenameEditField.Value = fullfilename;
            datal = readDFM17(fullfilename);
%             datal = readDFM17(convertCharsToStrings(app.FIlenameEditField.Value));
            ul = -(datal.Ws) .* sind(datal.Wd); % from MetPy
            vl = -(datal.Ws) .* cosd(datal.Wd);
            min3d = app.min_3DSlider.Value;
            t_l = find(datal.Alt>=min3d,1,'first');
            max3d = app.max_3DSlider.Value;
            if max3d>max(datal.Alt)
                max3d = max(datal.Alt);
                app.max_3DSlider.Value = max(datal.Alt);
            end
            t_u = find(datal.Alt>=max3d,1,'first');
            pmaxt = max([vl(t_l:t_u),ul(t_l:t_u)]);
            pmint = min([vl(t_l:t_u),ul(t_l:t_u)]);
            pmax = max(pmaxt);
            pmin = min(pmint);
            plot3(app.plot_3D,vl(t_l:t_u),datal.Alt(t_l:t_u),ul(t_l:t_u),'r');
            hold(app.plot_3D,'on');
            grid(app.plot_3D,'on');
            xlim(app.plot_3D,[pmin pmax]);
            zlim(app.plot_3D,[pmin pmax]);
            zlabel(app.plot_3D,'v'); ylabel(app.plot_3D,'Alt'); xlabel(app.plot_3D,'u');
            title(app.plot_3D,'3D u and v plot')
            app.Max_altEditField.Value = max3d;
            app.Min_altEditField.Value = min3d;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            d = append(app.Main_DirectoryEditField.Value,app.Data_DirectoryEditField.Value);
            app.i =1;
            il = app.i;
            t = fullfile(d, "*.txt");
            files = dir(t);
            app.filesp = files;
            current = files(il).name;
            current = fullfile(d, current);
            app.data = readRadioSondeData(current);
            [~, idx] = max(app.data.Alt);
            app.data = app.data(1:idx, :);
            app.data = app.data(app.data.Alt > 12000, :);
            if isempty(app.data)
                app.WarningsEditField.Value = 'No data above 12000m';
            end
            RS = 5;
            app.u = -(app.data.Ws) .* sind(app.data.Wd); % from MetPy
            app.v = -(app.data.Ws) .* cosd(app.data.Wd); %    
            [app.Alt, app.u, app.v, app.temp, app.bvFreqSquared] = ... 
            preprocessDataNoResample(app.data.Alt, app.u, app.v, app.data.T, app.data.P, RS);
            app.Current_FileEditField.Value = files(il).name;
            wll = app.Min_ALTSpinner.Value;
            wul = app.Max_ALTSpinner.Value;
            if wul>app.data.Alt(end)
                app.WarningsEditField.Value = 'MaxALT exceeds data range';
            else
                app.WarningsEditField.Value = ' ';
            end
            app.lower = find(app.data.Alt>=wll,1,'first');
            app.upper = find(app.data.Alt>=wul,1,'first');
            lowerl = app.lower;
            upperl = app.upper;
            ul = app.u;
            vl = app.v;
            plot(app.UIAxes5 ,ul(lowerl:upperl),vl(lowerl:upperl))
            axis(app.UIAxes5,'equal')
            ylabel(app.UIAxes5,'v'); xlabel(app.UIAxes5,'u');
            title(app.UIAxes5,' ')
        end

        % Button pushed function: Next_FileButton
        function Next_FileButtonPushed(app, event)
            d = append(app.Main_DirectoryEditField.Value,app.Data_DirectoryEditField.Value);
            
            t = fullfile(d, "*.txt");
            files = dir(t);
            app.i = app.i+1;
            il = app.i;
            if app.i > size(files)
                app.WarningsEditField.Value = 'END of flight data';
            end
            current = files(il).name;
            current = fullfile(d, current);
            app.data = readRadioSondeData(current);
            [~, idx] = max(app.data.Alt);
            app.data = app.data(1:idx, :);
            app.data = app.data(app.data.Alt > 12000, :);
            if isempty(app.data)
                app.WarningsEditField.Value = 'No data above 12000m';
                app.i = app.i+1;
            end
            RS = 5;
            app.u = -(app.data.Ws) .* sind(app.data.Wd); % from MetPy
            app.v = -(app.data.Ws) .* cosd(app.data.Wd); %    
            [app.Alt, app.u, app.v, app.temp, app.bvFreqSquared] = ... 
            preprocessDataNoResample(app.data.Alt, app.u, app.v, app.data.T, app.data.P, RS);
            app.Current_FileEditField.Value = files(il).name;
            wll = app.Min_ALTSpinner.Value;
            wul = app.Max_ALTSpinner.Value;
            if wul>app.data.Alt(end)
                app.WarningsEditField.Value = 'MaxALT exceeds data range';
            else
                app.WarningsEditField.Value = ' ';
            end
            app.lower = find(app.data.Alt>=wll,1,'first');
            app.upper = find(app.data.Alt>=wul,1,'first');
            lowerl = app.lower;
            upperl = app.upper;
            ul = app.u;
            vl = app.v;
            plot(app.UIAxes5 ,ul(lowerl:upperl),vl(lowerl:upperl))
            axis(app.UIAxes5,'equal')
            ylabel(app.UIAxes5,'v'); xlabel(app.UIAxes5,'u');
            title(app.UIAxes5,' ')
        end

        % Button pushed function: Save_DataButton
        function Save_DataButtonPushed(app, event)
            fprintf("%d, %d\n", app.Alt(app.lower), app.Alt(app.upper));
            T = table(app.Alt(app.lower:app.upper), app.u(app.lower:app.upper), app.v(app.lower:app.upper), app.temp(app.lower:app.upper), app.bvFreqSquared(app.lower:app.upper));
            T.Properties.VariableNames = {'Alt' 'u' 'v' 'temp' 'bv2'};
            [~, n, ~] = fileparts(app.filesp(app.i).name);
            fname = sprintf("%s\\%03u_%s-%d-%d_%s.txt",append(app.Main_DirectoryEditField.Value,app.Save_Data_DIrectoryEditField.Value),app.i, n, app.Alt(app.lower), app.Alt(app.upper), app.RotationDropDown.Value);
            writetable(T, fname);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            selpath = uigetdir;
            d =  selpath;

            t = fullfile(d, "*.txt");
            files = dir(t); 
            axs = [];
            series_figure = figure;
            hodo_fig = figure; offset = 10;
            arrow_offset = 1 / 15;
            counter = 1;
            already_seen = [];
            flight_num = [];
            offsets = [];
            coriolisFreq = coriolisFrequency(30.25);
%             unique_flights = strings(1,length(files));
%             for i = 1:length(files)
%                 a = sprintf('%02u',i);
%                 h = append('F',a);
%                 unique_flights(i) = h;
%             end
            unique_flights = ["F01","F02","F03","F04"];
            for k=1:size(unique_flights, 2)
                for i=1:size(files)
                    flight_number = files(i).name(1:3);
                    flight_number(1) = 'F';
                if strcmp(flight_number, unique_flights(k)) == 0
                    %fprintf("%s %s\n", unique_flights(k), flight_number);
                    continue;
                end
                % if str2num(flight_number(2:end)) ~= 23
                %     continue;
                % end
                data = readtable(fullfile(d, files(i).name));
                set(0, 'CurrentFigure', hodo_fig);
                ax = subplot(5, 5, i);
                axis (ax,'equal'); % this is needed to keep plots circular
                pbaspect(ax,[1 1 1])
                grid on
                axs = [axs;ax];
                eps = fit_ellipse(data.u, data.v, ax);
                hold on
                plot(data.u, data.v, 'k.')
                lambda_z = 2*(data.Alt(end) - data.Alt(1));
                m = 2*pi / lambda_z;
                p = eps.phi;
                rot = [cos(p) -sin(p); sin(p) cos(p)];
                uv = [data.u data.v];
                uvrot = rot*uv';
                urot = uvrot(1, :);
                dT = data.temp(2:end) - data.temp(1:end-1);
                dz = data.Alt(2:end) - data.Alt(1:end-1);
                wf = eps.long_axis / eps.short_axis;
                bvMean = mean(data.bv2);
                intrinsicFreq = coriolisFreq*wf;
                %fprintf("%0.10f, %0.10f ", (coriolisFreq^2 * m^2)/abs(bvMean), wf^2 - 1);
                k_h = sqrt((coriolisFreq^2*m^2)/(abs(bvMean))*(wf^2 - 1)); % horizontal wavenumber (1 / meters)
                %fprintf("%f %f\n", intrinsicFreq, wf);
                intrinsicHorizPhaseSpeed = intrinsicFreq / k_h; % m/s
                fprintf("m:%f, lz:%f, h:%f,, bv:%f\n", m, lambda_z, intrinsicHorizPhaseSpeed, bvMean);
                k_h_2 = sqrt((intrinsicFreq^2 - coriolisFreq^2 )* (m^2 / abs(bvMean)));
                int2 = intrinsicFreq/k_h_2;
                % fprintf("m:%f, lz:%f, h:%f, kh:%f\n", m, lambda_z, intrinsicHorizPhaseSpeed, int2);
                dTdz = dT./dz;
                eta = mean(dTdz.*urot(1:end-1));
                title({append('Wave #',num2str(i));intrinsicFreq})
                if eta < 0
                    p = p - pi;
                end
                str = sprintf("f: %s, ^{w}/{f}=%0.1f, t=%0.1f, m_z=%0.1f", ...
                    files(i).name(1:3), wf, azimuthFromUnitCircle(rad2deg(p)),...
                    lambda_z/1000);
                %title(str);
                fprintf("%s\n", str);
                set(0, 'CurrentFigure', series_figure);
                xlim([5, 155]);
                ylim([12, 33]);
                Alt_of_detection_km = mean(data.Alt) / 1000;
                x1 = counter*offset;
                x2 = counter*offset + wf*cosd(rad2deg(p));
                y1 = Alt_of_detection_km;
                y2 = Alt_of_detection_km + wf*sind(rad2deg(p));
                hold on;
                p1 = [x1 y1];
                p2 = [x2 y2];
                dp = p2-p1;
                %quiver(p1(1),p1(2),dp(1),dp(2), 'AutoScale', 'on', 'color','#9a0200', 'linewidth', 2);
                [xf, yf] = ds2nfu([x1 x2], [y1 y2]);
                A = strsplit(files(i).name,'_');
                if ismember('Clockwise.txt',A)
                    Ac = '#A2142F';
                elseif ismember('Counter-Clockwise.txt',A)
                    Ac = '#0072BD';
                else
                    Ac = '#77AC30';
                end
                annotation(gcf, 'arrow', xf,yf, 'color', Ac, 'LineWidth', 2) %plot arrow angle of propagation length corresponds to magnitude of intrinsic frequency
                xc = [xf(1) - 0.015, xf(2)];
                yc = [yf(1) + 0.02, yf(1)];
%                 annotation(gcf, 'line', xc, yc, 'String', num2str(i))
                annotation(gcf, 'textarrow', xc, yc, 'String', num2str(i),'HeadStyle','none')
%                 if strcmp(flight_number, "F23")
%                     if Alt_of_detection_km > 20 && Alt_of_detection_km < 24
%                         xc = [xf(1) - 0.125, xf(2)-0.02];
%                         yc = [yf(1) + 0.1, yf(1)+0.05];
%                         annotation(gcf, 'textarrow', xc, yc, 'String', 'Eclipse Wave #2')
%                     elseif Alt_of_detection_km > 20
%                         xc = [xf(1) - 0.125, xf(2)- 0.02];
%                         yc = [yf(1) + 0.1, yf(1)+0.05];
%                         annotation(gcf, 'textarrow', xc, yc, 'String', 'Eclipse Wave #3')
%                     end
%                     fprintf("%f\n", Alt_of_detection_km);
%                 end
                %plot([x1 x2], [y1 y2], 'color', '#9a0200', 'linewidth', 3);
                placeholder = [counter*offset counter*offset];
                plot(placeholder, [12, 32.5], 'k'); % plot Altitude in km.
                end
                offsets = [offsets, counter*offset];
                pbaspect(ax,[1 1 1])
                counter = counter + 1;
            end
            % 10 -> 150
%             linkaxes(axs, 'xy');
            set(0, 'CurrentFigure', hodo_fig);
            sgtitle("All hodographs for the radiosonde campaign")
            set(0, 'CurrentFigure', series_figure);
            % %offsets = offsets(1:end-1);
            flight_num = flight_num(1:end-1, :);
            xticks(offsets);
            xticklabels(unique_flights); 
            set(gca,'XTickLabelRotation', 45, 'fontsize', 16)
            ylabel("Altitude of detection (km)") 
            xlabel("Flight number")
            title("Propagation direction and detection Altitude of gravity waves (hodograph method)", 'fontsize', 16);

        end

        % Value changed function: Min_ALTSpinner
        function Min_ALTSpinnerValueChanged(app, event)
            value = app.Min_ALTSpinner.Value;
            app.wl = value;
            wll = app.wl;
            wul = app.Max_ALTSpinner.Value;
            app.lower = find(app.data.Alt>=wll,1,'first');
            app.upper = find(app.data.Alt>=wul,1,'first');
            lowerl = app.lower;
            upperl = app.upper;
            ul = app.u;
            vl = app.v;
            plot(app.UIAxes5 ,ul(lowerl:upperl),vl(lowerl:upperl))
            axis(app.UIAxes5,'equal')
            ylabel(app.UIAxes5,'v'); xlabel(app.UIAxes5,'u');
        end

        % Value changed function: Max_ALTSpinner
        function Max_ALTSpinnerValueChanged(app, event)
            value = app.Max_ALTSpinner.Value;
            app.wu = value;
            wll = app.Min_ALTSpinner.Value;
            wul = app.wu;
            if wul>app.data.Alt(end)
                app.WarningsEditField.Value = 'MaxALT exceeds data range';
            else
                app.WarningsEditField.Value = ' ';
            end
            
            app.lower = find(app.data.Alt>=wll,1,'first');
            app.upper = find(app.data.Alt>=wul,1,'first');
            lowerl = app.lower;
            upperl = app.upper;
            ul = app.u;
            vl = app.v;
            plot(app.UIAxes5 ,ul(lowerl:upperl),vl(lowerl:upperl))
            axis(app.UIAxes5,'equal')
            ylabel(app.UIAxes5,'v'); xlabel(app.UIAxes5,'u');
        end

        % Value changed function: min_3DSlider
        function min_3DSliderValueChanged(app, event)
            value = app.min_3DSlider.Value;
            datal = readDFM17(convertCharsToStrings(app.FIlenameEditField.Value));
            ul = -(datal.Ws) .* sind(datal.Wd); % from MetPy
            vl = -(datal.Ws) .* cosd(datal.Wd);
            min3d = app.min_3DSlider.Value;
            t_l = find(datal.Alt>=min3d,1,'first');
            max3d = app.max_3DSlider.Value;
            if max3d>max(datal.Alt)
                max3d = max(datal.Alt);
                app.max_3DSlider.Value = max(datal.Alt);
            end
            t_u = find(datal.Alt>=max3d,1,'first');
            plot3(app.plot_3D,vl(t_l:t_u),datal.Alt(t_l:t_u),ul(t_l:t_u),'r');
            zlabel(app.plot_3D,'v'); ylabel(app.plot_3D,'Alt'); xlabel(app.plot_3D,'u');
            app.Min_altEditField.Value = value;
        end

        % Value changed function: max_3DSlider
        function max_3DSliderValueChanged(app, event)
            value = app.max_3DSlider.Value;
            datal = readDFM17(convertCharsToStrings(app.FIlenameEditField.Value));
            ul = -(datal.Ws) .* sind(datal.Wd); % from MetPy
            vl = -(datal.Ws) .* cosd(datal.Wd);
            min3d = app.min_3DSlider.Value;
            t_l = find(datal.Alt>=min3d,1,'first');
            max3d = app.max_3DSlider.Value;
            if max3d>max(datal.Alt)
                max3d = max(datal.Alt);
                app.max_3DSlider.Value = max(datal.Alt);
            end
            t_u = find(datal.Alt>=max3d,1,'first');
            plot3(app.plot_3D,vl(t_l:t_u),datal.Alt(t_l:t_u),ul(t_l:t_u),'r');
            zlabel(app.plot_3D,'v'); ylabel(app.plot_3D,'Alt'); xlabel(app.plot_3D,'u');
            app.Max_altEditField.Value = value;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
%             fullfilename = app.Data_FileEditField_2.Value;
            clear HL;
            [file, path] = uigetfile('*.txt');
            fullfilename = fullfile(path,file);
            ax_1 = app.UIAxes6;
            data = readSondeFile(fullfilename);
            w = data.Ws; %wind Speed in m/s
            alt = data.Alt; %altitude (MSL) in m
            
            % Cut out data that was obtained after burst
            [maxa,maxaidx] = max(alt);
            ws = w(1:maxaidx);
            height = alt(1:maxaidx)-alt(1);
            ws = ws(height > 12000, :);
            height = height(height > 12000, :);
            %find local max 
            LMX = islocalmax(ws);
            LMN = islocalmin(ws);
            %initialize logical array
            levels = false(length(ws),1);
            
            for k = 1:length(ws) 
                if LMX(k) == 1 %each local max is assessed
                    stpidx = find(height >= height(k)+1000,1); %find index of height 1000 meters above local max height
                    if ~isempty(stpidx) % stpidx will be an empty array when height of local max + 1000 m is larger than burst height
                        if length(height)>stpidx % avoid looking at maximums that are within 1000 meters of burst height
                            wl = min(ws(k:stpidx)); % find minumum wind speed between local max height and 1000 m above
                            if ws(k)-wl >= 7.5 %difference must me greater than 7.5 to be considered significant shear
                                levels(k) = true; %set logical value to true at the height index
                            end
                        end
                    end
                end
            end
            
            HL = height(levels); % use logical array to determine heights of significant wind shear
%             t = max([size(height(LMX)),size(height(LMN))]);
%             HL_max = zeros(t,1);
%             HL_min = zeros(t,1);
            HL_max = height(LMX);
            HL_min = height(LMN);
            t = abs(size(HL_max)-size(HL_min));
            z = zeros(max(t),1);
            if size(HL_max,1) > size(HL_min,1)
                HL_min = cat(1,HL_min,z);
            elseif size(HL_max,1) < size(HL_min,1)
                HL_max = cat(1,HL_max,z);
            end
            HL_all = [HL_max, HL_min];
            
            if isempty(ax_1) % Allows this code to be used without the GUI, do not get rid of
                f1 = figure(); 
                ax_1 = axes(f1);
            end
            % Clears plot after loading a new file in the GUI
            plot(ax_1, ws, height, 'DisplayName', 'wind speed','Color','#7E2F8E',"LineWidth",1.5)
            hold(ax_1, 'on');
            plot(ax_1, ws(LMX), height(LMX),'r^', 'DisplayName', 'local max')
            plot(ax_1, ws(LMN), height(LMN),'b^', 'DisplayName', 'local min');
            
            for i = 1:length(HL)
                yline(ax_1, HL(i), 'DisplayName', num2str(HL(i))); %Plot all of the significant shear levels
            end
            
            hold(ax_1, 'off');
            title(ax_1,'Height (m) vs Wind Speed (m/s)')
            ylabel(ax_1,'Height (m)')
            xlabel(ax_1, 'Wind Speed (m/s)')
            legend(ax_1);
            
            app.filenameLabel.Text = file;
            app.UITable2.Data = HL;
            app.UITable.Data = HL_all;
        end

        % Button pushed function: DirectoryButton
        function DirectoryButtonPushed(app, event)
            selpath = uigetdir;
            app.Main_DirectoryEditField.Value = selpath;
        end

        % Button pushed function: RunButton_2
        function RunButton_2Pushed(app, event)
            warning('off', 'MATLAB:polyfit:RepeatedPointsOrRescale');
            warning('off', 'MATLAB:Axes:NegativeLimitsInLogAxis');
            addpath('./wave_matlab/'); % for wavelet analysis
            addpath('./dnsfnu/'); % for plot coordinates
            addpath('./acf/'); % for autocorrelation function
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %                           User defined variables                       %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            dataDirectory = app.dataDirectoryEditField.Value;
            saveDirectory = app.saveDirectoryEditField.Value;
            % The w/f happened with no correction to wind direction, a window of
            % 12km - 40km, and a heightSamplingFrequency of 5m.
            % heightSamplingFrequency of 7m makes a 90 w/f show up.
%             showPowerSurfaces = app.Power_SurfaceSwitch.Value; % Do you want to show the wavelet transform power surfaces?
            if app.Power_SurfaceSwitch.Value == "true"
                showPowerSurfaces = true;
            else
                showPowerSurfaces = false;
            end
%             save = app.Save_DataSwitch.Value; % Do you want to save the data? It will save in saveDirectory.
            if app.Save_DataSwitch.Value == "true"
                save = true;
            else
                save = false;
            end
            lowerCutOffAltitude = 12000; % Altitude where you want to start analysis
            upperCutOffAltitude = 40000; % Altitude where you want to end analysis - 
            % a value of 40000 will go to the highest point in the profile.
            latitude = app.latitudeEditField.Value; % Latitude of launch location.
            heightSamplingFrequency = 5;
            printData = true;
         
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %                            End of user editing                         %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%             diary wavelet_log.txt
%             diary on
            textFiles = fullfile(dataDirectory, "*.txt");
            files = dir(textFiles);
            f1 = figure;
            f2 = figure;
            set(0, 'CurrentFigure', f1);
            hold on;
            set(0, 'CurrentFigure', f2);
            hold on;
            counter = 0;
            first = true;
            minLat = Inf;
            minLon = Inf;
            maxLat = -Inf;
            maxLon = -Inf;
            % Iterate over files in dataDirectory
            for i=1:size(files)
                current = files(i).name;
                fprintf("Current file: %s\n", current);
                current = fullfile(dataDirectory, current);
                try
                    % All analysis logic is in doAnalysis
                    [latitudeArray, longitudeArray, altitude, data, ~, ~, ~, ~] = doAnalysis(current, ...
                        save, saveDirectory, showPowerSurfaces, lowerCutOffAltitude, upperCutOffAltitude,...
                        latitude, heightSamplingFrequency, printData);
                    altitude = seconds(altitude);
                    % the rest of the code here is plotting and error checking.
                    if isempty(data)
                        continue;
                    end
                    % Get latitude of bounding box around all radiosondes
                    mLat = min(latitudeArray);
                    mLon = min(longitudeArray);
                    mxLat = max(latitudeArray);
                    mxLon = max(longitudeArray);
                    if mxLon > maxLon
                        maxLon = mxLon;
                    end
                    if mxLat > maxLat
                        maxLat = mxLat;
                    end
                    if mLat < minLat
                        minLat = mLat;
                    end
                    if mLon < minLon
                        minLon = mLon;
                    end
                    % plot the latitude, longitude, and altitude in 3d
                    set(0, 'CurrentFigure', f1);
                    plot3(longitudeArray, latitudeArray, altitude);
                    % 3D plot magnitudes of quiver have to be 0 or else the plot is 
                    % weirdly scaled. The 3D plot is just for altitude of detection and
                    % propagation direction.
                    magnitudes = 0*data.axial_ratio + 0.1;
                    angle = data.propagation_dir;
                    quiver3(data.lon_of_detection, data.lat_of_detection, ...
                        data.alt_of_detection_km*1000, magnitudes.*cosd(angle), ...
                        magnitudes.*sind(angle), zeros(size(angle), 'like', angle), 0);
                    set(0, 'CurrentFigure', f2);
                    offset = 10;
                    % Plot a vertical line with placeholder, each one offset by 10 from
                    % each other on the x-axis.
                    altitude = seconds(altitude);
                    placeholder = zeros(size(altitude), 'like', altitude) + (counter*offset);
                    plot(placeholder, altitude/1000, 'k'); % plot altitude in km.
                    hold on;
                    xlim([-15, 160]);
                    ylim([9, 60]);
                    magnitudes = data.axial_ratio;
                    % The magnitudes of the red lines are the axial ratios of the
                    % gravity waves - axial ratio = intrinsic frequency / coriolis
                    % frequency.
                    % The for loop below just plots the red lines in the direction of
                    % propagation of the gravity wave
                    for q=1:size(data.alt_of_detection_km)
                        x1 = counter*offset;
                        x2 = counter*offset + magnitudes(q)*cosd(angle(q));
                        y1 = data.alt_of_detection_km(q);
                        y2 = data.alt_of_detection_km(q) + magnitudes(q)*sind(angle(q));
                        [xf, yf] = ds2nfu([x1 x2], [y1 y2]);
                        annotation(gcf, 'arrow', xf,yf, 'color', '#9a0200', 'LineWidth', 2)
                        %plot([x1 x2], [y1 y2], 'color', '#9a0200', 'linewidth', 3);
                    end
                    scaleFactor = 15;
                    if first
                        indicesForFilenames = i;
                        offsets = counter*offset;
                        first = false;
                    else
                        indicesForFilenames = cat(2, indicesForFilenames, i);
                        offsets = cat(2, offsets, counter*offset);
                    end
                    counter = counter + 1;
                catch e
                    if (strcmp(e.identifier, 'MATLAB:table:UnrecognizedVarName'))
                        fprintf("Data file %s does not contain a variable needed for analysis. Rerun sounding.\n", files(i).name);
                        fprintf("----------------------------\n");
                        continue;
                    end
                    rethrow(e);
                end
                    fprintf("----------------------------\n");
            end
            set(0, 'CurrentFigure', f1);
            % xlim([minLon maxLon])
            % ylim([minLat maxLat])
            xlabel('Longitude (deg)');
            ylabel("Latitude (deg)");
            zlabel("Altitude (m)");
            title("Gravity wave detection altitudes and directions (deg cw from North)");
            set(0, 'CurrentFigure', f2);
            allFiles = dir(textFiles);
            filenames = allFiles(indicesForFilenames)';
            
            for i=1:size(filenames, 2)
               filenames(i).name = filenames(i).name(1:3);
               filenames(i).name(1) = 'F';
            end
            
            xticks(offsets);
            yticks([0 10 20 30])
            xticklabels(["0", "10", "20", "30"]) 
            xticklabels({filenames.name});
            set(gca,'XTickLabelRotation', 45, 'fontsize', 16)
            ylabel("Altitude of detection (km)")
            xlabel("Flight number")
            title("Propagation direction and detection altitude of gravity waves", 'fontsize', 16);
%             diary off
%             temp = regexp(fileread('wavelet_log.txt'), '\r?\n', 'split');
%             app.log_outputTextArea.Value = temp;
            

        end

        % Button pushed function: dataDirectoryButton
        function dataDirectoryButtonPushed(app, event)
            selpath = uigetdir;
            app.dataDirectoryEditField.Value = selpath;
        end

        % Button pushed function: saveDirectoryButton
        function saveDirectoryButtonPushed(app, event)
            selpath = uigetdir;
            app.saveDirectoryEditField.Value = selpath;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TitlePageTab = uitab(app.TabGroup);
            app.TitlePageTab.Title = 'Title Page';
            app.Image = uiimage(app.TitlePageTab);
            app.Image.ImageSource = 'Labcoats_Logo.png';
            app.HodographMethodforGravityWaveDetectionLabel = uilabel(app.TitlePageTab);
            app.HodographMethodforGravityWaveDetectionLabel.Text = 'Hodograph Method for Gravity Wave Detection';
            app.DevelopedbyThomasColliganZachYapKatelynPowellLabel = uilabel(app.TitlePageTab);
            app.DevelopedbyThomasColliganZachYapKatelynPowellLabel.Text = 'Developed by: Thomas Colligan, Zach Yap, Katelyn Powell';
            app.wind_shearTab = uitab(app.TabGroup);
            app.wind_shearTab.Title = 'wind_shear';
            app.RunButton = uibutton(app.wind_shearTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.UITable = uitable(app.wind_shearTab);
            app.UITable.ColumnName = {'Shear_Max'; 'Shear_Min'};
            app.UITable.RowName = {};
            app.UIAxes6 = uiaxes(app.wind_shearTab);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            app.UITable2 = uitable(app.wind_shearTab);
            app.UITable2.ColumnName = {'Column 1'};
            app.UITable2.RowName = {};
            app.filenameLabel = uilabel(app.wind_shearTab);
            app.filenameLabel.Text = 'file name';
            app.SelectRunSelectdatafileforwindsheardetectionLabel = uilabel(app.wind_shearTab);
            app.SelectRunSelectdatafileforwindsheardetectionLabel.Text = {'Select "Run".'; 'Select data file for wind shear detection.'};
            app.Plot_3DTab = uitab(app.TabGroup);
            app.Plot_3DTab.Title = 'Plot_3D';
            app.Plot_3DButton = uibutton(app.Plot_3DTab, 'push');
            app.Plot_3DButton.ButtonPushedFcn = createCallbackFcn(app, @Plot_3DButtonPushed, true);
            app.Plot_3DButton.Text = 'Plot_3D';
            app.plot_3D = uiaxes(app.Plot_3DTab);
            title(app.plot_3D, 'Title')
            xlabel(app.plot_3D, 'X')
            ylabel(app.plot_3D, 'Y')
            app.min_3DSliderLabel = uilabel(app.Plot_3DTab);
            app.min_3DSliderLabel.Text = 'min_3D';
            app.min_3DSlider = uislider(app.Plot_3DTab);
            app.min_3DSlider.Limits = [10000 33000];
            app.min_3DSlider.ValueChangedFcn = createCallbackFcn(app, @min_3DSliderValueChanged, true);
            app.min_3DSlider.Value = 10000;
            app.max_3DSliderLabel = uilabel(app.Plot_3DTab);
            app.max_3DSliderLabel.Text = 'max_3D';
            app.max_3DSlider = uislider(app.Plot_3DTab);
            app.max_3DSlider.Limits = [10000 33000];
            app.max_3DSlider.ValueChangedFcn = createCallbackFcn(app, @max_3DSliderValueChanged, true);
            app.max_3DSlider.Value = 33000;
            app.FIlenameEditFieldLabel = uilabel(app.Plot_3DTab);
            app.FIlenameEditFieldLabel.Text = 'FIlename';
            app.FIlenameEditField = uieditfield(app.Plot_3DTab, 'text');
            app.FIlenameEditField.Tooltip = {'Format Example: C:\Users\user\Desktop\eclipseData\W8_L1_1100UTC_072220_ZASUS_Profile.txt'};
            app.Max_altEditFieldLabel = uilabel(app.Plot_3DTab);
            app.Max_altEditFieldLabel.Text = 'Max_alt';
            app.Max_altEditField = uieditfield(app.Plot_3DTab, 'numeric');
            app.Max_altEditField.ValueDisplayFormat = '%.0f';
            app.Min_altEditFieldLabel = uilabel(app.Plot_3DTab);
            app.Min_altEditFieldLabel.Text = 'Min_alt';
            app.Min_altEditField = uieditfield(app.Plot_3DTab, 'numeric');
            app.Min_altEditField.ValueDisplayFormat = '%.0f';
            app.Label_2 = uilabel(app.Plot_3DTab);
            app.Label_2.Text = {'Select "Plot_3D".'; 'Navigate and select the flight data file you would like to plot'; 'Adjust the sliders to determine ellipse altitudes. The 3D plot can be rotated using options given in upper right corner of the image.'};
            app.Hodograph_RunTab = uitab(app.TabGroup);
            app.Hodograph_RunTab.Title = 'Hodograph_Run';
            app.StartButton = uibutton(app.Hodograph_RunTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Next_FileButton = uibutton(app.Hodograph_RunTab, 'push');
            app.Next_FileButton.ButtonPushedFcn = createCallbackFcn(app, @Next_FileButtonPushed, true);
            app.Next_FileButton.Text = 'Next_File';
            app.Save_DataButton = uibutton(app.Hodograph_RunTab, 'push');
            app.Save_DataButton.ButtonPushedFcn = createCallbackFcn(app, @Save_DataButtonPushed, true);
            app.Save_DataButton.Text = 'Save_Data';
            app.Data_DirectoryEditFieldLabel = uilabel(app.Hodograph_RunTab);
            app.Data_DirectoryEditFieldLabel.Text = 'Data_Directory';
            app.Data_DirectoryEditField = uieditfield(app.Hodograph_RunTab, 'text');
            app.Data_DirectoryEditField.Tooltip = {'Data_Directory is the folder the data is in, formatted ".\folder_name"'};
            app.Data_DirectoryEditField.Value = '.\eclipseData';
            app.UIAxes5 = uiaxes(app.Hodograph_RunTab);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            app.Current_FileEditField_2Label = uilabel(app.Hodograph_RunTab);
            app.Current_FileEditField_2Label.Text = 'Current_File';
            app.Current_FileEditField = uieditfield(app.Hodograph_RunTab, 'text');
            app.WarningsEditFieldLabel = uilabel(app.Hodograph_RunTab);
            app.WarningsEditFieldLabel.Text = 'Warnings';
            app.WarningsEditField = uieditfield(app.Hodograph_RunTab, 'text');
            app.Save_Data_DIrectoryEditFieldLabel = uilabel(app.Hodograph_RunTab);
            app.Save_Data_DIrectoryEditFieldLabel.Text = 'Save_Data_DIrectory';
            app.Save_Data_DIrectoryEditField = uieditfield(app.Hodograph_RunTab, 'text');
            app.Save_Data_DIrectoryEditField.Tooltip = {'Save_Data_Directory is the folder the ellipse data files will be saved, formatted ".\folder_name"'};
            app.Save_Data_DIrectoryEditField.Value = '.\hodograph';
            app.Main_DirectoryEditFieldLabel = uilabel(app.Hodograph_RunTab);
            app.Main_DirectoryEditFieldLabel.Text = 'Main_Directory';
            app.Main_DirectoryEditField = uieditfield(app.Hodograph_RunTab, 'text');
            app.Main_DirectoryEditField.Tooltip = {'Main_Directory is the file location without the folder data is in'};
            app.Min_ALTSpinnerLabel = uilabel(app.Hodograph_RunTab);
            app.Min_ALTSpinnerLabel.Text = 'Min_ALT';
            app.Min_ALTSpinner = uispinner(app.Hodograph_RunTab);
            app.Min_ALTSpinner.Step = 100;
            app.Min_ALTSpinner.Limits = [12000 33000];
            app.Min_ALTSpinner.ValueDisplayFormat = '%.0f';
            app.Min_ALTSpinner.ValueChangedFcn = createCallbackFcn(app, @Min_ALTSpinnerValueChanged, true);
            app.Min_ALTSpinner.Value = 12000;
            app.Max_ALTSpinnerLabel = uilabel(app.Hodograph_RunTab);
            app.Max_ALTSpinnerLabel.Text = 'Max_ALT';
            app.Max_ALTSpinner = uispinner(app.Hodograph_RunTab);
            app.Max_ALTSpinner.Step = 100;
            app.Max_ALTSpinner.Limits = [12000 33000];
            app.Max_ALTSpinner.ValueDisplayFormat = '%.0f';
            app.Max_ALTSpinner.ValueChangedFcn = createCallbackFcn(app, @Max_ALTSpinnerValueChanged, true);
            app.Max_ALTSpinner.Value = 25000;
            app.Label_3 = uilabel(app.Hodograph_RunTab);
            app.Label_3.Text = {'1. Select "Directory" and find folder that contains'; 'the "Data_Directory" folder and "Save_Data"'; 'folder'; '2. Make sure "Data_Directory" folder and'; '"Save_Data" folder are correct'; '3. Select "Start".'; '4. Adjust Spinners to the altitude that detected gravity'; 'wave ellipses were found in the "Plot_3D" tab.'; '5. Determine using the maximum altitude'; 'spinner if the ellipse is traveling clockwise'; 'or counter-clockwise, use the rotation'; 'drop down to record direction.'; '6. Continue in the same file adjusting altitudes'; 'to the various ellipses in the data file, saving the'; 'ellipses as you go.'; '7. Once all ellipses are recorded, select'; '"Next File" and continue this process until'; 'complete. **You will need to change the'; 'file name in the "Plot_3D" tab, Data_File line.'};
            app.Image3 = uiimage(app.Hodograph_RunTab);
            app.Image3.ImageSource = 'untitled1.png';
            app.Exampleellipsewithaltituderangeof18400to19300mLabel = uilabel(app.Hodograph_RunTab);
            app.Exampleellipsewithaltituderangeof18400to19300mLabel.Text = 'Example ellipse with altitude range of 18400 to 19300 m';
            app.RotationDropDownLabel = uilabel(app.Hodograph_RunTab);
            app.RotationDropDownLabel.Text = 'Rotation';
            app.RotationDropDown = uidropdown(app.Hodograph_RunTab);
            app.RotationDropDown.Items = {'Clockwise', 'Counter-Clockwise'};
            app.RotationDropDown.Value = 'Clockwise';
            app.DirectoryButton = uibutton(app.Hodograph_RunTab, 'push');
            app.DirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @DirectoryButtonPushed, true);
            app.DirectoryButton.Text = 'Directory';
            app.Plot_HodographTab = uitab(app.TabGroup);
            app.Plot_HodographTab.Title = 'Plot_Hodograph';
            app.GridLayout = uigridlayout(app.Plot_HodographTab);
            app.PlotButton = uibutton(app.GridLayout, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.Label_4 = uilabel(app.GridLayout);
            app.Label_4.Text = {'Select "Plot"'; 'Navigate and select the hodograph data folder you would like to plot'; 'A new window should open with all the files plotted that are located in the save folder.'; ' '; 'Propagation Plot Legend:'; ''; 'The angle of the arrow corresponds to the horizontal propagation direction '; '(North is the positive Y direction)'; ''; 'Blue - Counter-Clockwise'; 'Northern Hemisphere - Propagating Downward '; 'Southern Hemisphere - Propagating Upward'; ' '; 'Red - Clockwise'; 'Northern Hemisphere - Propagating Upward '; 'Southern Hemisphere - Propagating Downward'; ''; ' Green - No direction data'};
            app.Wavelet_analysisTab = uitab(app.TabGroup);
            app.Wavelet_analysisTab.Title = 'Wavelet_analysis';
            app.dataDirectoryButton = uibutton(app.Wavelet_analysisTab, 'push');
            app.dataDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @dataDirectoryButtonPushed, true);
            app.dataDirectoryButton.Text = 'dataDirectory';
            app.dataDirectoryEditFieldLabel = uilabel(app.Wavelet_analysisTab);
            app.dataDirectoryEditFieldLabel.Text = 'dataDirectory';
            app.dataDirectoryEditField = uieditfield(app.Wavelet_analysisTab, 'text');
            app.dataDirectoryEditField.Value = '.\eclipseData\';
            app.saveDirectoryButton = uibutton(app.Wavelet_analysisTab, 'push');
            app.saveDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @saveDirectoryButtonPushed, true);
            app.saveDirectoryButton.Text = 'saveDirectory';
            app.saveDirectoryEditFieldLabel = uilabel(app.Wavelet_analysisTab);
            app.saveDirectoryEditFieldLabel.Text = 'saveDirectory';
            app.saveDirectoryEditField = uieditfield(app.Wavelet_analysisTab, 'text');
            app.saveDirectoryEditField.Value = '.\Profile\';
            app.RunButton_2 = uibutton(app.Wavelet_analysisTab, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunButton_2Pushed, true);
            app.RunButton_2.Text = 'Run';
            app.Power_SurfaceSwitchLabel = uilabel(app.Wavelet_analysisTab);
            app.Power_SurfaceSwitchLabel.Text = 'Power_Surface';
            app.Power_SurfaceSwitch = uiswitch(app.Wavelet_analysisTab, 'slider');
            app.Power_SurfaceSwitch.Items = {'true', 'false'};
            app.Power_SurfaceSwitch.Value = 'true';
            app.Save_DataSwitchLabel = uilabel(app.Wavelet_analysisTab);
            app.Save_DataSwitchLabel.Text = 'Save_Data';
            app.Save_DataSwitch = uiswitch(app.Wavelet_analysisTab, 'slider');
            app.Save_DataSwitch.Items = {'true', 'false'};
            app.Save_DataSwitch.Value = 'true';
            app.latitudeEditFieldLabel = uilabel(app.Wavelet_analysisTab);
            app.latitudeEditFieldLabel.Text = 'latitude';
            app.latitudeEditField = uieditfield(app.Wavelet_analysisTab, 'numeric');
            app.latitudeEditField.Value = 45;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Hodograph_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end